classdef physiomon < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    PhysioMonv301UIFigure  matlab.ui.Figure
    FileMenu               matlab.ui.container.Menu
    StartMenu              matlab.ui.container.Menu
    SaveMenu               matlab.ui.container.Menu
    EditMenu               matlab.ui.container.Menu
    onDisplayEdit          matlab.ui.container.Menu
    onDeviceEdit           matlab.ui.container.Menu
    HelpMenu               matlab.ui.container.Menu
    aboutphysiomonMenu     matlab.ui.container.Menu
    Toolbar                matlab.ui.container.Toolbar
    startToolbar           matlab.ui.container.toolbar.PushTool
    saveToolbar            matlab.ui.container.toolbar.ToggleTool
    displayToolbar         matlab.ui.container.toolbar.PushTool
    deviceToolbat          matlab.ui.container.toolbar.PushTool
    infoToolbar            matlab.ui.container.toolbar.PushTool
    hiddenPanel            matlab.ui.container.Panel
    statusWindow           matlab.ui.control.EditField
    LED_1                  matlab.ui.control.Lamp
    LED_2                  matlab.ui.control.Lamp
    LED_3                  matlab.ui.control.Lamp
  end

  
  properties (Access = private)
    statusBar               % shows information
    dlgDisplayEdit;         % dialog for editing display settings
    dlgDeviceEdit;          % dialog for device editing
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, start)
      
      % create the statusbar
      
      app.statusBar = statusbar(app.statusWindow,[app.LED_1, app.LED_2, app.LED_3]);
    end

    % Menu selected function: onDisplayEdit
    function onDisplayEditSelected(app, event)
      app.dlgDisplayEdit = settings_popup(app,[],[]);
    end

    % Menu selected function: onDeviceEdit
    function onDeviceEditMenuSelected(app, event)
      app.dlgDeviceEdit = devphysiodaq_popup(app,[],[]);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.PhysioMonv301UIFigure = uifigure('Visible', 'off');
      app.PhysioMonv301UIFigure.Name = 'PhysioMon v3.01';
      app.FileMenu = uimenu(app.PhysioMonv301UIFigure);
      app.FileMenu.Text = 'File';
      app.StartMenu = uimenu(app.FileMenu);
      app.StartMenu.Text = 'Start';
      app.SaveMenu = uimenu(app.FileMenu);
      app.SaveMenu.Text = 'Save';
      app.EditMenu = uimenu(app.PhysioMonv301UIFigure);
      app.EditMenu.Text = 'Edit';
      app.onDisplayEdit = uimenu(app.EditMenu);
      app.onDisplayEdit.MenuSelectedFcn = createCallbackFcn(app, @onDisplayEditSelected, true);
      app.onDisplayEdit.Text = 'display';
      app.onDeviceEdit = uimenu(app.EditMenu);
      app.onDeviceEdit.MenuSelectedFcn = createCallbackFcn(app, @onDeviceEditMenuSelected, true);
      app.onDeviceEdit.Text = 'device';
      app.HelpMenu = uimenu(app.PhysioMonv301UIFigure);
      app.HelpMenu.Text = 'Help';
      app.aboutphysiomonMenu = uimenu(app.HelpMenu);
      app.aboutphysiomonMenu.Text = 'about physiomon';
      app.Toolbar = uitoolbar(app.PhysioMonv301UIFigure);
      app.startToolbar = uipushtool(app.Toolbar);
      app.saveToolbar = uitoggletool(app.Toolbar);
      app.displayToolbar = uipushtool(app.Toolbar);
      app.deviceToolbat = uipushtool(app.Toolbar);
      app.infoToolbar = uipushtool(app.Toolbar);
      app.LED_3 = uilamp(app.PhysioMonv301UIFigure);
      app.LED_2 = uilamp(app.PhysioMonv301UIFigure);
      app.LED_1 = uilamp(app.PhysioMonv301UIFigure);
      app.statusWindow = uieditfield(app.PhysioMonv301UIFigure, 'text');
      app.hiddenPanel = uipanel(app.PhysioMonv301UIFigure);
      app.hiddenPanel.Title = 'Panel';
      app.hiddenPanel.Visible = 'off';
      % Show the figure after all components are created
      app.PhysioMonv301UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = physiomon(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.PhysioMonv301UIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.PhysioMonv301UIFigure)
    end
  end
end